classdef Iskaffe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                   matlab.ui.Figure
        MainFigureGrid               matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        RawFilesTab                  matlab.ui.container.Tab
        RFTGridLayout                matlab.ui.container.GridLayout
        RFTTable                     matlab.ui.control.Table
        RFTFolderGridLayout          matlab.ui.container.GridLayout
        EditFieldLabel               matlab.ui.control.Label
        RFTFolderEditField           matlab.ui.control.EditField
        RFTFolderButton              matlab.ui.control.Button
        RFTButtonsGridLayout         matlab.ui.container.GridLayout
        RFTButtonConvert             matlab.ui.control.Button
        RTFButtonLoad                matlab.ui.control.Button
        LoadedLinesTab               matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        ListBox                      matlab.ui.control.ListBox
        Label_2                      matlab.ui.control.Label
        ProcessTab                   matlab.ui.container.Tab
        ProcessTab_Grid              matlab.ui.container.GridLayout
        SurveyProcessingPanel        matlab.ui.container.Panel
        SurveyProcessingGrid         matlab.ui.container.GridLayout
        SurveyBoundaries_Label       matlab.ui.control.Label
        File_Button                  matlab.ui.control.Button
        Clear_Button                 matlab.ui.control.Button
        AnalyzesurveyButton          matlab.ui.control.Button
        ShowSurveyAnalysis_CheckBox  matlab.ui.control.CheckBox
        ExportSurveyAnalysis_Button  matlab.ui.control.Button
        Edit_Button                  matlab.ui.control.Button
        Draw_Button                  matlab.ui.control.Button
        DisplayPanel                 matlab.ui.container.Panel
        DisplayGrid                  matlab.ui.container.GridLayout
        ButtonMotionTextArea         matlab.ui.control.TextArea
        ButtonDownTextArea           matlab.ui.control.TextArea
        AnalysisResultsPanel         matlab.ui.container.Panel
        AnalysisResults_Grid         matlab.ui.container.GridLayout
        Label_3                      matlab.ui.control.Label
        AnalysisResults_TextArea     matlab.ui.control.TextArea
        LineProcessing_Panel         matlab.ui.container.Panel
        LineProcessing_Grid          matlab.ui.container.GridLayout
        GridSize_EditField           matlab.ui.control.NumericEditField
        GridsizemLabel               matlab.ui.control.Label
        Grid_Button                  matlab.ui.control.Button
        Analyze_Button               matlab.ui.control.Button
        GriddedDataDisplayed_DropDownMenu  matlab.ui.control.DropDown
        GriddeddatadisplayedDropDownLabel  matlab.ui.control.Label
        ShowLineAnalysis_CheckBox    matlab.ui.control.CheckBox
        MainAxes                     matlab.ui.control.UIAxes
        SurveyResultAxes             matlab.ui.control.UIAxes
        RFTContextMenu               matlab.ui.container.ContextMenu
        SelectallfilesMenu           matlab.ui.container.Menu
        SelectconvertedfilesMenu     matlab.ui.container.Menu
        DeselectallfilesMenu         matlab.ui.container.Menu
        InverseselectionMenu         matlab.ui.container.Menu
        LLTContextMenu               matlab.ui.container.ContextMenu
        UnloadselectedlinesMenu      matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % general properties
        CoffeeCodeRootFolder % Description
        FDataVersion
        FData = []; % cell array of loaded fData
        FDataModeList = [];  % list of all modes in loaded data
        FDataModeInd1P = []; % index in mode list for each ping of each line
        
        % raw data properties
        rawDataFilesList = [];
        idxConverted
        idxLoaded
        
        % configuration
        datagramSource = 'X8';
        ellips = '';
        tmproj = '';
        
        % Data analysis
        mosaicMinBeamAngle = [];
        mosaicInPolygon = []; % mosaic cells inside ROI
        mosaicMissing = []; % mosaic cells missing
        
    end
    
    
    methods (Access = private)
        
        % Update version number here        
        function ver = iskaffe_version(app)
            ver = '0.1.0';
        end
        
        % Update Raw Files Tab
        function updateRawFilesTable(app,keepSelection)
            
            % save list of files selected
            if exist('keepSelection','var') && keepSelection==1 && ~isempty(app.RFTTable.Data)
                idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            end
            
            % list raw_files in search path
            rawDataFolder = app.RFTFolderEditField.Value;
            app.rawDataFilesList = CFF_list_raw_files_in_dir(rawDataFolder);
            
            % check which are already converted
            app.idxConverted = CFF_are_raw_files_converted(app.rawDataFilesList);
            
            % check which are currently loaded
            app.idxLoaded = CFF_are_raw_files_loaded(app.rawDataFilesList, app.FData);
            
            % prepare array
            n_rawfiles = numel(app.rawDataFilesList);
            [disp_folder, filename, ext] = CFF_fileparts_as_cell(CFF_onerawfileonly(app.rawDataFilesList));
            disp_files = strcat(filename, ext);
            for ii = 1:n_rawfiles
                if iscell(app.rawDataFilesList{ii})
                    % paired file
                    disp_files{ii} = strcat(disp_files{ii}, ' (paired)');
                end
            end
            new_entry = [num2cell(false(n_rawfiles, 1)), disp_files, disp_folder];
            
            % make table invisble while we change all the visuals
            app.RFTTable.Visible = 'off';
            
            % remove current style
            removeStyle(app.RFTTable);
            
            % set new data
            app.RFTTable.Data = new_entry;
            
            % set files colors depending on status
            if ~isempty(new_entry)
                idx2subs = @(idx) cat(2,find([idx;0]),2.*ones(numel(find(idx)),1)); % function to turn files idx to table subscript indices
                styleNotConverted = uistyle('FontColor',[0.5 0.5 0.5]); % gray
                addStyle(app.RFTTable,styleNotConverted,'cell',idx2subs(~app.idxConverted));
                styleConvertedNotLoaded = uistyle('FontWeight','bold','FontColor','k'); % black
                addStyle(app.RFTTable,styleConvertedNotLoaded,'cell',idx2subs(app.idxConverted&~app.idxLoaded));
                styleLoaded = uistyle('FontWeight','bold','FontColor',[0.4660 0.6740 0.1880]); % green
                addStyle(app.RFTTable,styleLoaded,'cell',idx2subs(app.idxConverted&app.idxLoaded));
            end
            
            % re-select files previously selected
            if exist('keepSelection','var') && keepSelection==1 && ~isempty(idxFilesSelected)
                app.RFTTable.Data(idxFilesSelected,1) = {true};
            end
            
            % make table visible again
            app.RFTTable.Visible = 'on';
            
        end
        
        % Update Loaded Lines Tab
        function updateLoadedLines(app)
            nLines = numel(app.FData);
            if nLines == 0
                app.ListBox.Items = {};
                return
            end
            % get names of loaded lines
            loadedLinesNames = cellfun(@(x) CFF_file_name(CFF_onerawfileonly(x.ALLfilename)), app.FData,'UniformOutput',true);
            % sort by time of first ping
            loadedLinesFirstPingSDN = cellfun(@(x) x.X_1P_pingSDN(1), app.FData,'UniformOutput',true);
            [~,I] = sort(loadedLinesFirstPingSDN);
            loadedLinesNames = loadedLinesNames(I);
            % update listbox items
            app.ListBox.Items = loadedLinesNames;
        end
        
        % Update Map
        function updateMap(app)
            
            % for test of pulse mode display
            % app.FData{1}.Ru_1D_PulseLength(1:50) = categorical(ones(1,50),1,{'Long CW'});
            % app.FData{2}.Ru_1D_PulseLength(1:100) = categorical(ones(1,100),1,{'Medium CW'});
            
            % number of lines loaded
            nLines = numel(app.FData);
            
            % exit here if no more lines
            if nLines == 0
                return
            end
            
            % get tags of all graphic objects in map
            allGraphObj = get(app.MainAxes,'Children');
            if ~isempty(allGraphObj)
                allGraphObjTags = unique(get(allGraphObj,'Tag'));
            else
                allGraphObjTags = {};
            end
            
            % default nav line color
            nav_col = [0 0 0]; % black
            
            % (re)initialize colorbar
            colormap(app.MainAxes,'default')
            colorbar(app.MainAxes,'off')
            colorbarOn = 0;
            
            % (re)initialize xlim and ylim for zoom extent
            xlim = [nan nan];
            ylim = [nan nan];
            cax  = [nan nan];
            
            % work line per line
            for ii = 1:nLines
                
                % get data
                fData = app.FData{ii};
                
                % Line ID
                lineID = num2str(fData.ID,'%.0f');
                
                %% PART 1: NAVIGATION
                
                % Line Navigation ID
                lineNavID = [lineID '_nav'];
                
                % Only draw the navigation if it is NOT already drawn
                if ~ismember(lineNavID, allGraphObjTags)
                    
                    % 1.1 draw main navigation line
                    plot(app.MainAxes,fData.X_1P_pingE,fData.X_1P_pingN,...
                        'Tag',lineNavID,...
                        'Visible','on',...
                        'Color',nav_col);
                    
                    % 1.2 draw points every dt seconds as subsampled navigation
                    dt = 60; % in seconds
                    pingTimeSec = 0.001.*(fData.X_1P_pingTSMIM-fData.X_1P_pingTSMIM(1));
                    idx_f = mod(ceil(pingTimeSec),dt)==0 & [0,diff(ceil(pingTimeSec))];
                    
                    plot(app.MainAxes,fData.X_1P_pingE(idx_f),fData.X_1P_pingN(idx_f),'.',...
                        'Tag',lineNavID,...
                        'Visible','on',...
                        'Color',nav_col);
                    
                    % 1.3 start and end symbols
                    
                    % OLD
                    % % draw circle as start of line
                    % plot(app.MainAxes,fData.X_1P_pingE(1),fData.X_1P_pingN(1),'o',...
                    %     'Tag',lineNavID,...
                    %     'Visible','on',...
                    %     'Color',nav_col);
                    %
                    % % end of line
                    % plot(app.MainAxes,fData.X_1P_pingE(end),fData.X_1P_pingN(end),'s',...
                    %     'Tag',lineNavID,...
                    %     'Visible','on',...
                    %     'Color',nav_col);
                    
                    nPings = numel(fData.X_1P_pingE);
                    iPingDir = min(5,nPings); % number of pings to use to establish heading for start/end symbols
                    
                    % at start of line, draw a triangle
                    
                    % set triangle side length to half the depth
                    L = 0.5*nanmean(fData.X8_BP_DepthZ(:,1)); % in m
                    
                    % start heading in deg
                    h = atan2d( ...
                        (fData.X_1P_pingE(iPingDir)-fData.X_1P_pingE(1)), ...
                        (fData.X_1P_pingN(iPingDir)-fData.X_1P_pingN(1)) );
                    
                    % X and Y positions of triangle vertices
                    lineStartX = fData.X_1P_pingE(1) + [-0.5*L*cosd(h),0.5*L*cosd(h),0.5*L*sind(h),-0.5*L*cosd(h)];
                    lineStartY = fData.X_1P_pingN(1) + [0.5*L*sind(h),-0.5*L*sind(h),0.5*L*cosd(h),0.5*L*sind(h)];
                    
                    % draw
                    plot(app.MainAxes,lineStartX,lineStartY,...
                        'Tag',lineNavID,...
                        'Visible','on',...
                        'Color',nav_col);
                    
                    % at end of line, draw a segment
                    
                    % set segment length to half the depth
                    L = 0.5*nanmean(fData.X8_BP_DepthZ(:,end));
                    
                    % end heading in deg
                    h = atan2d( ...
                        (fData.X_1P_pingE(end)-fData.X_1P_pingE(end-iPingDir+1)), ...
                        (fData.X_1P_pingN(end)-fData.X_1P_pingN(end-iPingDir+1)) );
                    
                    % X and Y positions of segment vertices
                    lineStartX = fData.X_1P_pingE(end) + [-0.5*L*cosd(h),0.5*L*cosd(h)];
                    lineStartY = fData.X_1P_pingN(end) + [0.5*L*sind(h),-0.5*L*sind(h)];
                    
                    % draw
                    plot(app.MainAxes,lineStartX,lineStartY,...
                        'Tag',lineNavID,...
                        'Visible','on',...
                        'Color',nav_col);
                    
                    % add click-on-object callback to all navigation
                    % elements
                    lineNavObj = findobj(app.MainAxes,'Tag',lineNavID);
                    for jj = 1:numel(lineNavObj)
                        lineNavObj(jj).ButtonDownFcn = createCallbackFcn(app, @MapObjectButtonDown, true);
                    end
                    
                end
                
                % Update zoom extents
                xlim(1) = nanmin(xlim(1),nanmin(fData.X_1P_pingE(:)));
                xlim(2) = nanmax(xlim(2),nanmax(fData.X_1P_pingE(:)));
                ylim(1) = nanmin(ylim(1),nanmin(fData.X_1P_pingN(:)));
                ylim(2) = nanmax(ylim(2),nanmax(fData.X_1P_pingN(:)));
                
                
                %% COVERAGE
                
                % Line Navigation ID
                lineCovID = [lineID '_cov'];
                
                % Only draw the coverage if it is NOT already drawn
                if ~ismember(lineCovID, allGraphObjTags)
                    
                    
                    % get coordinates of the outermost soundings starting
                    % from the top-left corner and going clockwise
                    E = [fData.X_BP_bottomEasting(1,:)'; ...
                        fData.X_BP_bottomEasting(:,end); ...
                        fliplr(fData.X_BP_bottomEasting(end,:))'; ...
                        flipud(fData.X_BP_bottomEasting(:,1))];
                    N = [fData.X_BP_bottomNorthing(1,:)'; ...
                        fData.X_BP_bottomNorthing(:,end); ...
                        fliplr(fData.X_BP_bottomNorthing(end,:))'; ...
                        flipud(fData.X_BP_bottomNorthing(:,1))];
                    pos = [E,N];
                    
                    %
                    
                    % then create polygon and load into map
                    cov = images.roi.Polygon( ...
                        'Position', pos, ...
                        'Color','black',...
                        'FaceAlpha', 0.1, ...
                        'InteractionsAllowed','none', ...
                        'LineWidth',1, ...
                        'Tag',lineCovID ...
                        );
                    cov.Parent = app.MainAxes;
                    
                end
                
                
                %% PART 2: GRIDDED DATA
                
                % flag for existence of data in this line
                lineDataExist = isfield(fData,'X_1_2DgridHorizontalResolution');
                if lineDataExist
                    
                    % ID for gridded data
                    lineDatID = [lineID '_dat'];
                    
                    % find gridded data object
                    lineDatObj = findobj(app.MainAxes,'Tag',lineDatID);
                    
                    % if it does not exist yet, create a dummy one with
                    % 'Visible' set to 'off'
                    if isempty(lineDatObj)
                        lineDatObj = imagesc(app.MainAxes,...
                            fData.X_1E_2DgridEasting,...
                            fData.X_N1_2DgridNorthing,...
                            fData.X_NE_bs,...
                            'Visible','off',...
                            'Tag',lineDatID);
                    end
                    
                    % then set data to display
                    switch app.GriddedDataDisplayed_DropDownMenu.Value
                        case '-'
                            set(lineDatObj,'Visible','off');
                        case 'Backscatter'
                            data = fData.X_NE_bs;
                            set(lineDatObj,'CData',data,...
                                'AlphaData',~isnan(data), ...
                                'Visible','on');
                            % adjust colour scale
                            colorbarOn = 1;
                            cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                        case 'Bathymetry'
                            data = fData.X_NE_bathy;
                            set(lineDatObj,'CData',data,...
                                'AlphaData',~isnan(data), ...
                                'Visible','on');
                            % adjust colour scale
                            colorbarOn = 1;
                            cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                        case 'Slope'
                            data = fData.X_NE_slope;
                            set(lineDatObj,'CData',data,...
                                'AlphaData',~isnan(data), ...
                                'Visible','on');
                            % adjust colour scale
                            colorbarOn = 1;
                            cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                        case 'Mode'
                            [~,gridPing] = ind2sub(size(fData.X8_BP_DepthZ),fData.X_NE_minIndexBP);
                            indNan = isnan(gridPing);
                            gridPing(indNan) = 1; %temporary
                            data = app.FDataModeInd1P{ii}(gridPing);
                            data(indNan) = NaN;
                            set(lineDatObj,'CData',data,...
                                'AlphaData',~isnan(data), ...
                                'Visible','on');
                            % adjust colour scale
                            colorbarOn = 1;
                            cax = [0.5 numel(app.FDataModeList)+0.5];
                    end
                    
                    % push grid to the bottom of the display stack so
                    % navigation stays on top
                    ch = get(app.MainAxes,'children'); % get all plots from app.MainAxes
                    [~,neworder] = sort(ismember(ch,lineDatObj)); % reorder the handles to move the background lines to the background
                    set(app.MainAxes,'children',ch(neworder)); % set the new order of all lines
                    
                    % Adjust zoom extents
                    xlim(1) = nanmin(xlim(1),nanmin(fData.X_1E_2DgridEasting(:)));
                    xlim(2) = nanmax(xlim(2),nanmax(fData.X_1E_2DgridEasting(:)));
                    ylim(1) = nanmin(ylim(1),nanmin(fData.X_N1_2DgridNorthing(:)));
                    ylim(2) = nanmax(ylim(2),nanmax(fData.X_N1_2DgridNorthing(:)));
                    
                end
                
                
                %% PART 3: BAD DATA
                
                % flag for whether line has been analyzed
                lineAnalysisExist = isfield(fData,'X_BP_goodData');
                
                % analysis graphic objects
                lineBadDataID = [lineID '_bad'];
                lineBadDataObj = findobj(app.MainAxes,'Tag',lineBadDataID);
                
                % to draw only if analysis data exist, but not objects yet
                if lineAnalysisExist && isempty(lineBadDataObj)
                    % bad soundings
                    idxBad = ~app.FData{ii}.X_BP_goodData;
                    plot(app.MainAxes,...
                        app.FData{ii}.X_BP_bottomEasting(idxBad),...
                        app.FData{ii}.X_BP_bottomNorthing(idxBad),...
                        'Tag',lineBadDataID,...
                        'Visible','on',...
                        'LineStyle','none',...
                        'Color','yellow',...
                        'Marker','*');
                    
                    % bad pings
                    idxBad = app.FData{ii}.X_1P_badPing;
                    plot(app.MainAxes,...
                        app.FData{ii}.X_BP_bottomEasting(:,idxBad),...
                        app.FData{ii}.X_BP_bottomNorthing(:,idxBad),...
                        'Tag',lineBadDataID,...
                        'Visible','on',...
                        'LineStyle','none',...
                        'Color','red',...
                        'Marker','*');
                end
                
            end
            
            % Set map view
            set(app.MainAxes,'YLim',ylim,'XLim',xlim);
            app.equalizeMapView(1.2);
            
            % color scale adjust
            if all(~isnan(cax))
                caxis(app.MainAxes,cax);
            end
            
            % set colorbar and colormap
            if colorbarOn
                cb = colorbar(app.MainAxes,'Location','east');
                
                % set data to display
                switch app.GriddedDataDisplayed_DropDownMenu.Value
                    case 'Bathymetry'
                        colormap(app.MainAxes,jet);
                    case 'Backscatter'
                        colormap(app.MainAxes,gray);
                    case 'Slope'
                        colormap(app.MainAxes,gray);
                    case 'Mode'
                        if numel(app.FDataModeList)==1
                            colormap(app.MainAxes,[jet(1);jet(1)]);
                        else
                            colormap(app.MainAxes,jet(numel(app.FDataModeList)));
                        end
                        set(cb,'Ticks',1:numel(app.FDataModeList),'ButtonDownFcn',@callbackColorbarButtonDown);
                end
            end
            
            function callbackColorbarButtonDown(~,~)
                message = '';
                for ii = 1:numel(app.FDataModeList)
                    message = [message, num2str(ii), ': ', app.FDataModeList{ii}, newline, newline];
                end
                message(end) = [];
                uialert(gcf,message,'Info',...
                    'Icon','info');
            end
            
        end
        
        function equalizeMapView(app, bufferRatio)
            % This function adjusts the xlim and ylim properties of the map
            % to ensure the map maintains equal units of m/pixel in X and Y
            % so the data never looks stretched.
            % This function is to be called when any of those properties
            % are changed, whether automatically (e.g. when loading new
            % data) or interactively (e.g. using zoom functions).
            
            % these are the current extents of the data view, in
            % easting/northing (meters)
            xlim = get(app.MainAxes,'XLim');
            ylim = get(app.MainAxes,'YLim');
            
            % The adjustment depends on how the "aspect ratio" (ratio of
            % height to width) of the data view compare to that
            % of the map frame.
            % If the map frame is "flatter" than the data view, we will fit
            % the height of the data view to the map frame and then adjust
            % automatically in width.
            % If the data view is "flatter" than the map frame, we will fit
            % the width of the data view to the map frame and then adjust
            % automatically in height.
            
            % aspect ratio of the data view
            reqAspectRatio = diff(ylim)/diff(xlim);
            
            % aspect ratio of the map frame
            pos = getpixelposition(app.MainAxes);
            mapAspectRatio = pos(4)/pos(3);
            
            % Instead of fitting the data view to the data frame exactly,
            % we allow in input some extra buffer. e.g. a value of 1.2
            % would mean that the frame width will represent 120% of the
            % data view width (if fitting in width). This way we "zoom-out"
            % a bit more compare to the original view. By default, we fit
            % exactly using a ratio of 1.
            if ~exist('bufferRatio','var')
                bufferRatio = 1;
            end
            
            if reqAspectRatio > mapAspectRatio
                % map frame is "flatter" than data view
                % -> Define ylim to fit the data in height
                ylim_new = [-diff(ylim),diff(ylim)]*bufferRatio/2 + ylim(1) + diff(ylim)/2;
                % X distance to ensure equal m per pixel in X and Y
                dx = diff(ylim_new)/mapAspectRatio;
                % define xlim with old view centeredd
                xlim_new = [-dx/2,dx/2] + xlim(1) + diff(xlim)/2;
            else
                % data view is "flatter" than map frame
                % -> Define xlim to fit the data in width
                xlim_new = [-diff(xlim),diff(xlim)]*bufferRatio/2 + xlim(1) + diff(xlim)/2;
                % Y distance to ensure equal m per pixel in X and Y
                dy = diff(xlim_new)*mapAspectRatio;
                % define ylim with old view centered
                ylim_new = [-dy/2,dy/2] + ylim(1) + diff(ylim)/2;
            end
            
            % set those new values to map
            set(app.MainAxes,'YLim',ylim_new,'XLim',xlim_new);
            
        end
        
        function updateAnalysisResultsLabel(app)
            
            % top text
            txt{1} = sprintf('Iskaffe v%s. Backscatter quality control results',iskaffe_version(app));
            txt{end+1} = sprintf('date: %s',datestr(now,'yyyy/mm/dd HH:MM:SS'));
            
            % modes
            txt{end+1} = '';
            txt{end+1} = 'Modes in dataset:';
            nModes = numel(app.FDataModeList);
            for jj = 1:nModes
                txtMode = strrep(app.FDataModeList{jj},newline,', ');
                txt{end+1} = sprintf('  * %i) %s',jj,txtMode);
            end

            % lines loaded
            txt{end+1} = '';
            txt{end+1} = 'Line processing:';
            N = numel(app.FData);
            
            tot_nSoundings = 0;
            tot_nBadSoundings = 0;
            tot_nPings = 0;
            tot_nBadPings = 0;
            for ii = 1:N
                % file
                filename = CFF_file_name(app.FData{ii}.ALLfilename{1},1);
                txt{end+1} = sprintf('%i/%i: %s',ii,N,filename);
                
                % modes
                if numel(app.FDataModeList)>1 % do only if more than one mode in dataset
                    indModesInFile = unique(app.FDataModeInd1P{ii});
                    nModes = numel(indModesInFile);
                    modeTxt = cell(nModes,1);
                    for jj = 1:nModes
                        modeTxt{jj} = sprintf('%i',indModesInFile(jj));
                    end
                    txt{end+1} = sprintf('  * mode(s): %s', strjoin(modeTxt,','));
                end
                
                % line analysis results
                if isfield(app.FData{ii},'X_BP_goodData')
                    % number of beams and pings
                    [nBeams, nPings] = size(app.FData{ii}.X_BP_goodData);
                    nSoundings = nBeams.*nPings;
                    % number of bad pings and bad beams
                    nBadSoundings = sum(sum(~app.FData{ii}.X_BP_goodData));
                    nBadPings = sum(app.FData{ii}.X_1P_badPing);
                    % text
                    txt{end+1} = sprintf('  * bad soundings: %i/%i (%.2f%%)', nBadSoundings, nSoundings, nBadSoundings.*100./nSoundings);
                    txt{end+1} = sprintf('  * bad pings: %i/%i (%.2f%%)', nBadPings, nPings, nBadPings.*100./nPings);
                    % add to totals
                    tot_nSoundings = tot_nSoundings + nSoundings;
                    tot_nBadSoundings = tot_nBadSoundings + nBadSoundings;
                    tot_nPings = tot_nPings + nPings;
                    tot_nBadPings = tot_nBadPings + nBadPings;
                end
            end
            
            % total line stats
            if tot_nSoundings>0
                txt{end+1} = '';
                txt{end+1} = 'Total line processing:';
                txt{end+1} = sprintf('  * bad soundings: %i/%i (%.2f%%)', tot_nBadSoundings, tot_nSoundings, tot_nBadSoundings.*100./tot_nSoundings);
                txt{end+1} = sprintf('  * bad pings: %i/%i (%.2f%%)', tot_nBadPings, tot_nPings, tot_nBadPings.*100./tot_nPings);
            end
            
            % missing area in mosaic
            if ~isempty(app.mosaicInPolygon)
                txt{end+1} = '';
                txt{end+1} = 'Survey processing:';
                nTot = sum(app.mosaicInPolygon(:));
                nMiss = sum(app.mosaicMissing(:));
                txt{end+1} = sprintf('missing mosaic cells: %i/%i (%.2f%%)', nMiss, nTot, nMiss.*100./nTot);
            end
            
            
            % assessment result
            txt{end+1} = '';
            txt{end+1} = 'Assessment result: PASS/FAIL';
            
            % set text and scroll to bottom
            app.AnalysisResults_TextArea.Value = txt;
            scroll(app.AnalysisResults_TextArea, 'bottom');
            
            % Iskaffe v0.0.0 - backscatter quality assessment
            % date: 2022/04/13 11:11:23
            %
            % Line processing results:
            % 1/N: filename
            %   * bad soundings: XXX/XXX (0.18%)
            %   * bad pings: XXX/XXX (0.18%)
            %   * Mode(s):
            %      * Mode blabla
            %      * Mode blabla
            % 2/N: filename
            %   * bad soundings: XXX/XXX (0.18%)
            %   * bad pings: XXX/XXX (0.18%)
            %   * Mode(s):
            %      * Mode blabla
            %      * Mode blabla2
            %
            % WARNING: More than one mode.
            %
            % Survey processing results:
            % Survey limits:
            %   9812,0992
            %   9812,0992
            %   9812,0992
            %   9812,0992
            % Grid size: 1 m
            % Missing mosaic cells: XXX/XXX (0.18%)
            %
            % Assessment result: PASS/FAIL
            
        end
        
        function cleanupMap(app)
            % This function deletes the objects belonging to lines that are
            % no longer loaded
            
            % get all graphic objects in map
            allGraphObj = get(app.MainAxes,'Children');
            % get ID of all loaded lines
            lineIDs = cellfun(@(x) num2str(x.ID,'%.0f'), app.FData,'UniformOutput',false);
            % delete obsolete graphic objects
            for ii = 1:numel(allGraphObj)
                graphObjTagLineID =  extract(allGraphObj(ii).Tag,digitsPattern);
                if ~ismember(graphObjTagLineID, lineIDs)
                    delete(allGraphObj(ii));
                end
            end
        end
        
        function zoomAllTheWayOut(app)
            % ZOOMALLTHEWAYOUT   Zoom out to include all objects in map view
            
            % get all graphical objects
            h = allchild(app.MainAxes);
            % initialize view limits
            xlim = [nan nan];
            ylim = [nan nan];
            % find view limits that include all objects
            for ii = 1:numel(h)
                if isprop(h(ii),'XData')
                    xlim(1) = min( xlim(1), min(h(ii).XData));
                    xlim(2) = max( xlim(2), max(h(ii).XData));
                    ylim(1) = min( ylim(1), min(h(ii).YData));
                    ylim(2) = max( ylim(2), max(h(ii).YData));
                elseif isprop(h(ii),'Position')
                    xlim(1) = min( xlim(1), min(h(ii).Position(:,1)));
                    xlim(2) = max( xlim(2), max(h(ii).Position(:,1)));
                    ylim(1) = min( ylim(1), min(h(ii).Position(:,2)));
                    ylim(2) = max( ylim(2), max(h(ii).Position(:,2)));
                end
            end
            % default limits where there are no objects in the map
            if any(isnan([xlim,ylim]))
                xlim = [0 1];
                ylim = [0 1];
            end
            % Set map view
            set(app.MainAxes,'YLim',ylim,'XLim',xlim);
            % Equalize map view, with a buffer
            app.equalizeMapView(1.2);
        end
        
        function updateButtons(app)
            % enable or disable buttons depending on what has been loaded
            
            %% Line processing panel
            
            % "Grid" and "Analyze" are only enabled if lines are loaded
            if ~isempty(app.FData)
                app.Grid_Button.Enable = 'on';
                app.Analyze_Button.Enable = 'on';
            else
                app.Grid_Button.Enable = 'off';
                app.Analyze_Button.Enable = 'off';
            end
            
            % "Gridded data displayed" is only enabled if at least one line 
            % has been gridded
            if ~isempty(app.FData) && any(cellfun(@(s) isfield(s,'X_1_2DgridHorizontalResolution'), app.FData))
                app.GriddedDataDisplayed_DropDownMenu.Enable = 'on';
            else
                app.GriddedDataDisplayed_DropDownMenu.Enable = 'off';
            end
            
            % "Show line analysis" is only enabled if at least one
            % line has been analyzed
            if ~isempty(app.FData) && any(cellfun(@(s) isfield(s,'X_BP_goodData'), app.FData))
                app.ShowLineAnalysis_CheckBox.Enable = 'on';
            else
                app.ShowLineAnalysis_CheckBox.Enable = 'off';
            end
            
            %% Survey processing panel
            
            % "File" and "Draw" are always enabled
            
            % "Edit" and "Clear" are only enabled if there is a ROI polygon
            % loaded 
            if ~isempty(findobj('Tag','ROI'))
                app.Edit_Button.Enable = 'on';
                app.Clear_Button.Enable = 'on';
            else
                app.Edit_Button.Enable = 'off';
                app.Clear_Button.Enable = 'off';
            end
            
            % "Mosaic" is only enabled if there is a ROI polygon loaded and
            % if at least one line has been gridded and analyzed
            if ~isempty(findobj('Tag','ROI')) && ...
                    ~isempty(app.FData) &&  ...
                    any(cellfun(@(s) all(isfield(s,{'X_1_2DgridHorizontalResolution','X_BP_goodData'})), app.FData))
                app.AnalyzesurveyButton.Enable = 'on';
            else
                app.AnalyzesurveyButton.Enable = 'off';
            end
            
            % "Show survey analysis" and "Export survey analysis" are only
            % enabled if survey has been analysed
            if ~isempty(app.mosaicMissing)
                app.ShowSurveyAnalysis_CheckBox.Enable = 'on';
                app.ExportSurveyAnalysis_Button.Enable = 'on';
            else
                app.ShowSurveyAnalysis_CheckBox.Enable = 'off';
                app.ExportSurveyAnalysis_Button.Enable = 'off';
            end
            
        end
        
        function deleteROIpolygon(app)
            % delete ROI polygon
            delete(findobj('Tag','ROI'));
        end
        
        function createROIpolygon(app, pos)
            % create ROI polygon
            % start with clearing any existing polygon
            app.deleteROIpolygon();
            if exist('pos','var')
                % create ROI from positions
                images.roi.Polygon( app.MainAxes,...
                    'Position', pos, ...
                    'FaceAlpha', 0, ...
                    'InteractionsAllowed','none', ...
                    'Tag','ROI');
            else
                % start ROI drawing mode
                h = drawpolygon(app.MainAxes,...
                    'FaceAlpha', 0.2, ...
                    'Tag','ROI');
                % when done, remove interactions and facealpha
                h.FaceAlpha = 0;
                h.InteractionsAllowed = 'none';
            end
        end
        
        function updateListOfModes(app)
            
            % reset the variables holding information on modes
            app.FDataModeList = {};
            app.FDataModeInd1P = cell(size(app.FData));
            
            % list of all mode fields possible
            % (see CFF_decode_RuntimeParameters.m). Will need update if we
            % update fData fields
            modeFieldList = {'PingMode',...
                'TxPulseForm',...
                'FrequencyKHz',...
                'DualSwathMode',...
                'RXarrayUse',...
                'SonarHeadUse',...
                'PulseLength',...
                'ReceiverFixedGainSettingDB'};
            
            % operate line by line
            for ii = 1:numel(app.FData)
                % get line fdata
                fData = app.FData{ii};
                % find mode fields from the list that exist in this line
                indFieldExist = cellfun(@(f) isfield(fData,['Ru_1D_' f]),modeFieldList);
                existingModeFieldList = sort(modeFieldList(indFieldExist));
                % for each runtime datagram, combine all mode parameters
                % into a single text entry
                nRuDtg = numel(fData.Ru_1D_Date);
                nMdFld = numel(existingModeFieldList);
                Ru_1D_mode_text = cell(1,nRuDtg);
                for jj = 1:nRuDtg
                    newModeEntry = cell(nMdFld,1);
                    for kk = 1:nMdFld
                        val = fData.(['Ru_1D_' existingModeFieldList{kk}])(jj);
                        if isnumeric(val)
                            val = num2str(val);
                        elseif iscategorical(val)
                            val = char(val);
                        end
                        newModeEntry{kk} = [existingModeFieldList{kk}, ': ', val];
                    end
                    Ru_1D_mode_text{jj} = strjoin(newModeEntry,newline);
                end
                % matching ping counters between the Runtime datagrams and
                % the X8 datagrams, get the mode of each ping
                nPings = numel(fData.X8_1P_Date);
                X8_1P_ind_mode = ones(1,nPings);
                for jj = 1:nRuDtg
                    idx = fData.X8_1P_PingCounter>=fData.Ru_1D_PingCounter(jj);
                    X8_1P_ind_mode(idx) = jj;
                end
                % find the modes in this line that did not exist so far,
                % and them to the list
                uniqueModesInLine = unique(Ru_1D_mode_text,'stable');
                for jj = 1:numel(uniqueModesInLine)
                    if ~ismember(uniqueModesInLine{jj},app.FDataModeList)
                        app.FDataModeList{end+1} = uniqueModesInLine{jj};
                    end
                end
                % find the index of each ping in the mode list
                [~,Locb] = ismember(Ru_1D_mode_text,app.FDataModeList);
                app.FDataModeInd1P{ii} = Locb(X8_1P_ind_mode);
            end
            
            % if more that one mode is detected
            if numel(app.FDataModeList)>1
                % switch display to mode (won't do anything if lines are
                % not loaded)
                app.GriddedDataDisplayed_DropDownMenu.Value = 'Mode';
                updateMap(app);
                % throw warning
                uialert(app.MainFigure,'Different modes were detected in the set of loaded lines','Multiple modes detected');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            fprintf('Starting Iskaffe v%s. Please wait...\n',iskaffe_version(app));
            
            %% update paths
            % Only when running in MATLAB
            if ~isdeployed
                % Add app folder to path
                AppRootFolder = fileparts(which('Iskaffe'));
                addpath(genpath(AppRootFolder));
                
                % Add Coffee code folder to path
                app.CoffeeCodeRootFolder = 'C:\Users\Schimel_Alexandre\Code\MATLAB\Espresso\toolboxes\coffee';
                if ~isfolder(app.CoffeeCodeRootFolder)
                    % Throw an alert to find manually a suitable coffee folder
                    app.CoffeeCodeRootFolder = uigetdir(AppRootFolder,'Select suitable CoFFee folder');
                end
                addpath(genpath(app.CoffeeCodeRootFolder));
            end
            
            %% visual setup
            
            % Make figure invisible while we finish the visual setup
            if isdeployed()
                app.MainFigure.Visible = 'off';
            end
            
            % Main figure icon and name
            app.MainFigure.Icon = 'icon_48.png';
            app.MainFigure.Name = sprintf('Iskaffe v%s',iskaffe_version(app));
            
            % Resize and reposition figure according to monitor's dimension
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            app.MainFigure.Position = [left bottom width height];
            
            % Set default folder location
            app.RFTFolderEditField.Value = userpath;
            
            % Initialize buttons enable/disable state
            app.updateButtons();
            
            %% non-visual setup
            
            % Make figure visible, the non-visual setup can happen after
            app.MainFigure.Visible = 'on';
            
            % Link main axes and survey result axes
            linkaxes([app.MainAxes, app.SurveyResultAxes]);
            
            % Record fData format version
            app.FDataVersion = CFF_get_current_fData_version();
            
            % Next section is setting up map interactions. It gets
            % complicated.
            % Ideally I want to have:
            % 1. zooming and panning as built-in interactions, resp using
            % the scroll wheel and left-click-and-dragging.
            % 2. Toolbar interactions 'export', 'pan', 'zoomin', 'zoomout',
            % and 'restoreview'
            % 3. Note I need to maintain the figure position and equalized
            % axes after any zoom
            % 4. Some customized interactions: getting coordinates (Easting
            % Northing) of the current mouse pointer and information from
            % an object when clicked on (e.g. file name).
            %
            % For now I have no figured how to make #1 work with the rest
            % so I am disabling the legacy built-in interactions, but
            % ideally have it work too.
            
            % 1. Customize the maps' available built-in interactions (i.e.
            % those available when NOT using the map's toolbar buttons)
            % enableDefaultInteractivity(app.MainAxes);
            % app.MainAxes.Interactions = [panInteraction zoomInteraction];
            
            % 2. Custom map toolbar
            enableLegacyExplorationModes(app.MainFigure);
            axtoolbar(app.MainAxes,{'export','pan','zoomin','zoomout','restoreview'});
            
            % 3. Post-zoom callback to equalize map axes
            h = zoom(app.MainFigure);
            h.ActionPostCallback = @(o,e) app.equalizeMapView();
            
            % 4. callback when clicking on the map
            app.MainAxes.ButtonDownFcn = createCallbackFcn(app, @MapObjectButtonDown, true);
            
            % 4. Callback when moving the pointer on the figure (can't have
            % such callback on the map alone)
            app.MainFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MainFigureWindowButtonMotion, true);
            
            % Initialize Raw Files table
            app.updateRawFilesTable();
            
            disp('Ready.');
            
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: RFTFolderEditField
        function RFTFolderEditFieldValueChanged(app, event)
            % Check if value is an existing folder
            requestedFolder = app.RFTFolderEditField.Value;
            if ~isfolder(requestedFolder)
                % Error dialog box
                uialert(app.MainFigure,'Folder not found','Invalid Folder','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure);
                % Reset previous field value
                app.RFTFolderEditField.Value = event.PreviousValue;
            else
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Button pushed function: RFTFolderButton
        function RFTFolderButtonPushed(app, event)
            % Open folder selection dialog box
            currentFolder = app.RFTFolderEditField.Value;
            newFolder = uigetdir(currentFolder,'Select folder of raw data files');
            figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
            if newFolder ~= 0
                % Update value of the FolderEditField
                app.RFTFolderEditField.Value = newFolder;
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Menu selected function: SelectallfilesMenu
        function RTFSelectAll(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {true}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: SelectconvertedfilesMenu
        function RTFSelectConverted(app, event)
            app.RFTTable.Data(:,1) = arrayfun(@(x) {x}, app.idxConverted);
        end

        % Menu selected function: DeselectallfilesMenu
        function RTFDeselectAll(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {false}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: InverseselectionMenu
        function RTFInverseSelection(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {~x}, app.RFTTable.Data(:,1));
        end

        % Button pushed function: RFTButtonConvert
        function RFTButtonConvertPushed(app, event)
            % Get idx of selected files
            idxFilesToConvert = cell2mat(app.RFTTable.Data(:,1));
            % Default value to be overwritten if relevant
            forceReconvert = 0;
            % Prompt if some files already converted
            if any(idxFilesToConvert.*app.idxConverted)
                N = sum(idxFilesToConvert.*app.idxConverted);
                if N == 1
                    questDlgTxt = sprintf('1 file for which conversion was requested is already converted.\n\nDo you want to re-convert it?');
                else
                    questDlgTxt = sprintf('%i files for which conversion was requested are already converted.\n\nDo you want to re-convert them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Raw Files Conversion', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        forceReconvert = 1;
                    case 'No'
                        forceReconvert = 0;
                    case 'Cancel'
                        % abort converting
                        return
                end
            end
            % Get filenames
            rawFilesList = app.rawDataFilesList(idxFilesToConvert);
            % Convert files
            CFF_convert_raw_files(rawFilesList,...
                'conversionType','seafloor',...
                'saveFDataToDrive',1,...
                'forceReconvert',forceReconvert,...
                'outputFData',0,...
                'abortOnError',0,...
                'convertEvenIfDtgrmsMissing',0,...
                'dr_sub',1,...
                'db_sub',1,...
                'comms','multilines');
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
        end

        % Button pushed function: RTFButtonLoad
        function RTFButtonLoadPushed(app, event)
            % Get idx of selected files
            idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            idxFilesToLoad = idxFilesSelected & ~app.idxLoaded;
            % Idx of files requested but not converted yet
            idxNeedConversionFirst = idxFilesToLoad & ~app.idxConverted;
            % Prompt if some files not already converted
            if any(idxNeedConversionFirst)
                N = sum(idxNeedConversionFirst);
                if N == 1
                    questDlgTxt = sprintf('1 file for which loading was requested has not been converted yet.\n\nDo you want to convert it and load it?');
                else
                    questDlgTxt = sprintf('%i files for which loading was requested have not been converted yet.\n\nDo you want to convert them and load them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Converted Files Loading', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        % Convert those files
                        CFF_convert_raw_files(app.rawDataFilesList(idxNeedConversionFirst),...
                            'conversionType','seafloor',...
                            'saveFDataToDrive',1,...
                            'forceReconvert',0,...
                            'outputFData',0,...
                            'abortOnError',0,...
                            'convertEvenIfDtgrmsMissing',0,...
                            'dr_sub',1,...
                            'db_sub',1,...
                            'comms','multilines');
                        % Update Raw Files Table contents
                        app.updateRawFilesTable(1);
                    case 'No'
                        % Remove non-converted files from loading list
                        idxFilesToLoad = logical(idxFilesToLoad.*app.idxConverted);
                    case 'Cancel'
                        % abort loading
                        return
                end
            end
            % Get filenames
            toLoadFilesList = app.rawDataFilesList(idxFilesToLoad);
            if isempty(toLoadFilesList)
                return
            end
            % Geoprocess files
            fData_new = CFF_geoprocess_files(toLoadFilesList,...
                'datagramSource',app.datagramSource,...
                'ellips',app.ellips,...
                'tmproj',app.tmproj,...
                'saveFDataToDrive',0,...
                'outputFData',1,...
                'abortOnError',0,...
                'comms','multilines');
            % by default, CFF_geoprocess_files outputs a struct for a
            % single file. Turn to cell array if a single file was loaded
            if isstruct(fData_new)
                fData_new = {fData_new};
            end
            % update app's geoprocessing parameters
            if isempty(app.tmproj)
                app.ellips = fData_new{1}.MET_ellips;
                app.tmproj = fData_new{1}.MET_tmproj;
            end
            if isempty(app.datagramSource)
                app.datagramSource = fData_new{1}.MET_datagramSource;
            end
            % Fix fData paths if necessary
            fData_new = CFF_fix_fData_paths(fData_new, toLoadFilesList);
            % Time-tag the individual fData structs
            for iF = 1:numel(fData_new)
                fData_new{iF}.ID = str2double(datestr(now,'yyyymmddHHMMSSFFF'));
                pause(1e-2); % pause here to ensure unique IDs
            end
            % add new fData to old one
            app.FData = [app.FData,fData_new];
            % Update loaded lines tab contents
            app.updateLoadedLines();
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
            % Update Map
            app.updateMap();
            % Update buttons
            app.updateButtons();
            % Update list of data modes
            app.updateListOfModes();
            % Update analysis results label
            updateAnalysisResultsLabel(app);
        end

        % Menu selected function: UnloadselectedlinesMenu
        function LLTUnload(app, event)
            % get names of lines to unload
            linesToUnload = app.ListBox.Value;
            % corresponding indices in fData
            nLines = numel(app.FData);
            idxUnload = false(nLines,1);
            for ii = 1:nLines
                thisLineName = CFF_file_name(CFF_onerawfileonly(app.FData{ii}.ALLfilename));
                if ismember(thisLineName, linesToUnload)
                    idxUnload(ii) = true;
                end
            end
            % remove lines from fData
            app.FData(idxUnload) = [];
            % Update loaded lines Tab
            updateLoadedLines(app);
            % Update Raw Files Tab (keep selection)
            app.updateRawFilesTable(1);
            % Cleanup Map
            cleanupMap(app);
            % Update buttons
            app.updateButtons();
            % Update list of data modes
            app.updateListOfModes();
        end

        % Button pushed function: Grid_Button
        function PTGrid_ButtonPushed(app, event)
            if isempty(app.FData)
                return
            end
            % grid requested lines
            app.FData = CFF_grid_lines(app.FData,...
                'res',app.GridSize_EditField.Value, ... % grid resolution
                'saveFDataToDrive', 0, ... % don't save (gridded) fData to drive
                'abortOnError', 0, ... % move on to next file
                'comms','multilines');
            % Set gridded data displayed to backscatter
            app.GriddedDataDisplayed_DropDownMenu.Value = 'Backscatter';
            % Update Map
            updateMap(app);
            % Update buttons enable state
            updateButtons(app);
            % Update analysis results label
            updateAnalysisResultsLabel(app);
        end

        % Button pushed function: File_Button
        function PTFile_ButtonPushed(app, event)
            % Import a boundary file for later mosaicking
            
            % open dialog box
            [filename, pathname, filterindex] = uigetfile( ...
                {'*.csv;*.txt', 'Text files'; ...
                '*.shp',       'Shapefiles'}, ...
                'Select survey limits file');
            figure(app.MainFigure); % uigetfile sometimes puts app in the background. Bring it back on top
            
            % if cancelling the dialog box, exit here
            if filename == 0
                return
            end
            
            % get filename
            file = fullfile(pathname, filename);
            
            % read file, test suitability, and process
            switch filterindex
                case 1
                    % text file
                    pos = readmatrix(file);
                    
                    % for text file we simply assume that the coordsinates
                    % are in the right projection. So we only test for
                    % suitability as polygon vertices 
                    
                    % test for suitability as vertices coordinates
                    % two columns only, X & Y
                    % 3 points minimum
                    % actual numbers, no nans
                    if size(pos,2)==2 &&  size(pos,1)>=3 &&  all(~isnan(pos(:)))
                        % test if lat/long
                        if all(abs(pos(:,1))<=180) && all(abs(pos(:,2))<=90)
                            % assuming input is lat/long in decimal degrees
                            if isempty(app.ellips) && isempty(app.tmproj)
                                % project does not have a CRS yet.
                                % get UTM zone from first vertex and set
                                % app CRS from this
                                [~,~,~,~,utmzone] = CFF_ll2tm(pos(1,1), pos(1,2),'wgs84','utm');
                                app.tmproj = ['utm' utmzone];
                                app.ellips = 'wgs84';
                            end
                            % project latlong using app CRS
                            [pos(:,1), pos(:,2)] = CFF_ll2tm(pos(:,1), pos(:,2), app.ellips, app.tmproj);
                        else
                            % assuming input is the correct UTM projection
                        end
                        isSuitable = 1;
                    else
                        isSuitable = 0;
                    end

                case 2
                    % shapefile
                    S = shaperead(file);
                    
                    % default as unsuitable
                    isSuitable = 0;
                    
                    % keep only polygons in shapefile
                    iPolyg = arrayfun(@(elm) strcmp(elm.Geometry,'Polygon'),S) == 1;
                    S = S(iPolyg);
                    
                    N = numel(S);
                    if N>1
                        % more than one polygon in shapefile, need to
                        % choose one
                        
                        % get attributes
                        Sfields = fields(S);
                        indf = ismember(Sfields,{'Geometry','BoundingBox','X','Y'});
                        Sfields = Sfields(~indf);
                        
                        % create string from attributes
                        clear list
                        for jj = 1:N
                            clear list0
                            for kk = 1:numel(Sfields)
                                f = Sfields{kk};
                                v = getfield(S(jj),f);
                                if isnumeric(v)
                                    v = num2str(v);
                                end
                                list0{kk} = sprintf('%s: %s',f,v);
                            end
                            list(jj,1) = join(list0,', ');
                        end
                        
                        % prompt for choice of polygon
                        [indx,tf] = listdlg('PromptString','Select a polygon', ...
                            'ListSize',[300 300], ...
                            'SelectionMode','single', ...
                            'ListString',list);
                        if tf==1
                            S = S(indx);
                        else
                            % cancel
                            S = [];
                        end
                    end
                    
                    % if we have a polygon
                    if ~isempty(S)
                        % get coordinates
                        pos = [S.X' S.Y'];
                        % remove nans
                        pos(isnan(pos(:,1))|isnan(pos(:,2)),:) = [];
                        % remove last vertex if same as first
                        if all(pos(end,:)==pos(1,:))
                            pos(end,:) = [];
                        end
                        % now we check and process for coordinate system
                        % get CRS from shapefile
                        info = shapeinfo(file);
                        if isa(info.CoordinateReferenceSystem,'geocrs')
                            % lat/long
                            if strcmp(info.CoordinateReferenceSystem.Name,'WGS 84')
                                if isempty(app.ellips) && isempty(app.tmproj)
                                    % project does not have a CRS yet.
                                    % get UTM zone from first vertex and set
                                    % app CRS from this
                                    [~,~,~,~,utmzone] = CFF_ll2tm(pos(1,1), pos(1,2),'wgs84','utm');
                                    app.tmproj = ['utm' utmzone];
                                    app.ellips = 'wgs84';
                                end
                                % project latlong using app CRS
                                [pos(:,1), pos(:,2)] = CFF_ll2tm(pos(:,1), pos(:,2), app.ellips, app.tmproj);
                                isSuitable = 1;
                            end
                        elseif isa(info.CoordinateReferenceSystem,'projcrs')
                            % projected coordinates
                            if strcmp(info.CoordinateReferenceSystem.ProjectionMethod,'Transverse Mercator')
                                % get zone
                                pat = "UTM zone " + digitsPattern(2) + ("N"|"S");
                                newStr = char(extract(info.CoordinateReferenceSystem.Name,pat));
                                filetmproj = ['utm' newStr(end-2:end)];
                                if isempty(app.ellips) && isempty(app.tmproj)
                                    % not loaded any data yet, so use that CRS
                                    % for the app
                                    app.tmproj = filetmproj;
                                    app.ellips = 'wgs84';
                                    isSuitable = 1;
                                else
                                    % check that it matches the app CRS
                                    if strcmp(filetmproj, app.tmproj)
                                        isSuitable = 1;
                                    end
                                end
                            end
                        end
                    end
            end

            % warning and exit if not suitable
            if ~isSuitable
                % Error dialog box
                uialert(app.MainFigure,'Boundary file not suitable','Boundary file not suitable','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure);
                return
            end

            comms = CFF_Comms('multilines');
            comms.start('Importing survey limits');
            
            % create polygon
            app.createROIpolygon(pos);
            
            comms.finish('Done');
            
            % zoom out to all graphical objects
            app.zoomAllTheWayOut();
            
            % update buttons
            app.updateButtons();
            
        end

        % Button pushed function: Clear_Button
        function PTClear_ButtonPushed(app, event)
            % delete ROI polygon
            app.deleteROIpolygon();
            % update buttons
            app.updateButtons();
            % DO NOT zoom out. Keep same map view
        end

        % Button pushed function: AnalyzesurveyButton
        function PTAnalyzesurveyButtonPushed(app, event)
            if isempty(app.FData)
                return
            end
            % area to mosaic
            roi = findobj('Tag','ROI');
            if ~isempty(roi)
                xy_roi = roi.Position;
            end
            % mosaic requested lines
            %             mosaicBathy = CFF_mosaic_lines(app.FData,...
            %                 'X_NE_bathy', ...
            %                 'xy_roi', xy_roi, ... % mosaic limits polygon
            %                 'res',[], ... % get grid size directly from fData
            %                 'mode','blend', ... % mosaicking mode
            %                 'comms','multilines');
            %             mosaicBS = CFF_mosaic_lines(app.FData,...
            %                 'X_NE_bs', ...
            %                 'xy_roi', xy_roi, ... % mosaic limits polygon
            %                 'res',[], ... % get grid size directly from fData
            %                 'mode','stitch', ... % mosaicking mode
            %                 'comms','multilines');
            app.mosaicMinBeamAngle = CFF_mosaic_lines(app.FData,...
                'X_NE_minBeamAngle', ...
                'xy_roi', xy_roi, ... % mosaic limits polygon
                'res',[], ... % get grid size directly from fData
                'mode','min', ... % mosaicking mode
                'comms','multilines');
            
            comms = CFF_Comms('multilines');
            comms.start('Finding missing data in mosaic');
            
            % find where data is missing in mosaic
            [xg,yg] = meshgrid(app.mosaicMinBeamAngle.xg,app.mosaicMinBeamAngle.yg);
            app.mosaicInPolygon = inpolygon(xg,yg,app.mosaicMinBeamAngle.x_pol,app.mosaicMinBeamAngle.y_pol);
            app.mosaicMissing = app.mosaicInPolygon & app.mosaicMinBeamAngle.weight==0;
            
            comms.finish('Done');
            
            % Delete any already existing results
            delete(findobj('Tag','surveyAnalysisResults'));
            
            % and display the new one
            imagesc(app.SurveyResultAxes,...
                app.mosaicMinBeamAngle.xg,...
                app.mosaicMinBeamAngle.yg,...
                app.mosaicMissing,...
                'AlphaData',app.mosaicMissing==1,...
                'Tag','surveyAnalysisResults');
            colormap(app.SurveyResultAxes,'cool')
            
            % for some reason, the YDir prop keeps being wrong
            set(app.SurveyResultAxes,'YDir','normal');
            
            % check box
            app.ShowSurveyAnalysis_CheckBox.Value = 1;
            
            % Update Map
            updateMap(app);
            
            % Update buttons
            app.updateButtons();
            
            % Update analysis results label
            updateAnalysisResultsLabel(app);
        end

        % Button pushed function: Analyze_Button
        function PTAnalyze_ButtonPushed(app, event)
            
            if isempty(app.FData)
                return
            end
            
            app.FData = CFF_analyze_backscatter(app.FData,...
                'comms','multilines');
            
            % Check the "Show line analysis results" box
            app.ShowLineAnalysis_CheckBox.Value = 1;
            % Update Map
            updateMap(app);
            % Update buttons enable state
            updateButtons(app);
            % Update analysis results label
            updateAnalysisResultsLabel(app);
            
        end

        % Value changed function: GriddedDataDisplayed_DropDownMenu
        function PTGriddedDataDisplayed_DropDownMenuValueChanged(app, event)
            if isempty(app.FData)
                return
            end
            % Update Map
            updateMap(app);
        end

        % Value changed function: ShowLineAnalysis_CheckBox
        function ShowLineAnalysis_CheckBox_ValueChanged(app, event)
            value = app.ShowLineAnalysis_CheckBox.Value;
            nLines = numel(app.FData);
            for ii = 1:nLines
                lineID = num2str(app.FData{ii}.ID,'%.0f');
                lineBadDataID = [lineID '_bad'];
                lineBadDataObj = findall(app.MainAxes,'Tag',lineBadDataID);
                set(lineBadDataObj,'Visible',value);
            end
        end

        % Callback function
        function MainFigureWindowButtonMotion(app, event)
            % Callback when pointer is moving over the figure
            
            % Determine if mouse is within map
            cp = app.MainFigure.CurrentPoint;
            isInAxes = cp(1) >= app.MainAxes.Position(1) && ...
                cp(1) <= sum(app.MainAxes.Position([1,3])) && ...
                cp(2) >= app.MainAxes.Position(2) && ...
                cp(2) <= sum(app.MainAxes.Position([2,4]));
            if isInAxes
                % Update displayed coordinates
                app.ButtonMotionTextArea.Value{1} = sprintf('E: %.2f', app.MainAxes.CurrentPoint(1,1));
                app.ButtonMotionTextArea.Value{2} = sprintf('N: %.2f', app.MainAxes.CurrentPoint(1,2));
            else
                % Clear displayed coordinates
                app.ButtonMotionTextArea.Value{1} = '';
                app.ButtonMotionTextArea.Value{2} = '';
            end
            
        end

        % Callback function
        function MapObjectButtonDown(app, event)
            % Callback when clicking on something in the map
            
            % click location info
            app.ButtonDownTextArea.Value{1} = sprintf('E: %.2f', event.IntersectionPoint(1));
            app.ButtonDownTextArea.Value{2} = sprintf('N: %.2f', event.IntersectionPoint(2));
            
            % event holds the object where it was clicked
            if strcmp(event.Source.Tag,'MainAxes')
                % click on empty space in the map
                app.ButtonDownTextArea.Value{3} = '';
                app.ButtonDownTextArea.Value{4} = '';
                
            elseif ~isempty(event.Source.Tag)
                % anything else should be a graphic object with ID
                
                % index in fData
                ID = str2double(cell2mat(extract(event.Source.Tag, digitsPattern)));
                ind = cellfun(@(x) x.ID==ID, app.FData);
                
                % get the filename
                lineName = CFF_file_name(app.FData{ind}.ALLfilename{1},1);
                
                % get closest ping
                dE = app.FData{ind}.X_1P_pingE - event.IntersectionPoint(1);
                dN = app.FData{ind}.X_1P_pingN - event.IntersectionPoint(2);
                [~,iPing] = min(sqrt(dE.^2+dN.^2));
                
                % get nadir beam
                [~,iBeam] = min(abs(app.FData{ind}.X8_BP_AcrosstrackDistanceY(:,iPing)));
                
                % get depth
                nadirDepth = app.FData{ind}.X8_BP_DepthZ(iBeam,iPing);
                
                % update text area
                app.ButtonDownTextArea.Value{3} = lineName;
                app.ButtonDownTextArea.Value{4} = sprintf('Z: %.2f',nadirDepth);
                
            end
            
            
        end

        % Value changed function: ShowSurveyAnalysis_CheckBox
        function ShowSurveyAnalysis_CheckBoxValueChanged(app, event)
            value = app.ShowSurveyAnalysis_CheckBox.Value;
            set(findobj('Tag','surveyAnalysisResults'),'Visible',value);
        end

        % Button pushed function: ExportSurveyAnalysis_Button
        function ExportSurveyAnalysis_ButtonPushed(app, event)
            
            % export date and time
            datetimenow = now;
            export_time_txt = datestr(datetimenow,'yyyy-mm-dd_HHMMSS');
            
            % export tif filename
            defaultfilename = fullfile(userpath,sprintf('Iskaffe_results_%s.tif',export_time_txt));
            [file,path] =  uiputfile('*.tif','Export survey analysis results',defaultfilename);
            
            % if cancelling the dialog box, exit here
            if file == 0
                return
            end
            
            % get filename
            filename = fullfile(path, file);
            
            comms = CFF_Comms('multilines');
            comms.start('Exporting analysis results');
            
            % map cells reference object
            xlimits = [app.mosaicMinBeamAngle.xg(1)-app.mosaicMinBeamAngle.res./2, app.mosaicMinBeamAngle.xg(end)+app.mosaicMinBeamAngle.res./2];
            ylimits = [app.mosaicMinBeamAngle.yg(1)-app.mosaicMinBeamAngle.res./2, app.mosaicMinBeamAngle.yg(end)+app.mosaicMinBeamAngle.res./2];
            rasterSize = size(app.mosaicMissing);
            R = maprefcells(xlimits,ylimits,rasterSize);
            
            % coordinate reference
            switch app.ellips
                case 'wgs84'
                    switch app.tmproj(1:3)
                        case 'utm'
                            switch app.tmproj(end)
                                case 'N'
                                    coordRefSysCode = str2num(['326' app.tmproj(4:5)]);
                                case 'S'
                                    coordRefSysCode = str2num(['327' app.tmproj(4:5)]);
                            end
                        otherwise
                            % not coded
                    end
                otherwise
                    % not coded
            end
            
            % write geotif file
            geotiffwrite(filename,app.mosaicMissing,R,'CoordRefSysCode',coordRefSysCode);
            
            % text file
            filename_txt = [filename(1:end-4), '.txt'];
            
            % write
            writecell(app.AnalysisResults_TextArea.Value,filename_txt, 'QuoteStrings', false);
            
            comms.finish('Done');
            
        end

        % Button pushed function: Draw_Button
        function Draw_ButtonPushed(app, event)
            
            % set warning
            selection = uiconfirm(app.MainFigure, ...
                {'About to start drawing the survey limits ROI.','This will clear any currently existing ROI.'},...
                'ROI drawing mode');
            switch selection
                case 'OK'
                    app.createROIpolygon();
                    
                    % update buttons
                    app.updateButtons();
            end
        end

        % Button pushed function: Edit_Button
        function Edit_ButtonPushed(app, event)
            
            % get ROI
            h = findobj('Tag','ROI');
            
            switch h.InteractionsAllowed
                case 'none'
                    % currently in normal mode. Pass in edit mode
                    
                    % set warning
                    selection = uiconfirm(app.MainFigure, ...
                        {'About to start editing the survey limits ROI.','When finished, press the "Edit" button again to confirm.'},...
                        'ROI editing mode');
                    
                    switch selection
                        case 'OK'
                            h.InteractionsAllowed = 'reshape';
                    end
                case 'reshape'
                    % currently in edit mode. Pass in normal mode
                    h.InteractionsAllowed = 'none';
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Iskaffe';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.MainFigureGrid = uigridlayout(app.MainFigure);
            app.TabGroup = uitabgroup(app.MainFigureGrid);
            app.RawFilesTab = uitab(app.TabGroup);
            app.RawFilesTab.Title = 'Raw files';
            app.RFTGridLayout = uigridlayout(app.RawFilesTab);
            app.RFTTable = uitable(app.RFTGridLayout);
            app.RFTTable.ColumnName = {''; 'File'; 'Folder'};
            app.RFTTable.RowName = {};
            app.RFTTable.ColumnSortable = [false true false];
            app.RFTTable.ColumnEditable = [true false false];
            app.RFTFolderGridLayout = uigridlayout(app.RFTGridLayout);
            app.EditFieldLabel = uilabel(app.RFTFolderGridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.RFTFolderEditField = uieditfield(app.RFTFolderGridLayout, 'text');
            app.RFTFolderEditField.ValueChangedFcn = createCallbackFcn(app, @RFTFolderEditFieldValueChanged, true);
            app.RFTFolderButton = uibutton(app.RFTFolderGridLayout, 'push');
            app.RFTFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RFTFolderButtonPushed, true);
            app.RFTFolderButton.Icon = 'foldericon.gif';
            app.RFTFolderButton.Text = '';
            app.RFTButtonsGridLayout = uigridlayout(app.RFTGridLayout);
            app.RFTButtonConvert = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RFTButtonConvert.ButtonPushedFcn = createCallbackFcn(app, @RFTButtonConvertPushed, true);
            app.RFTButtonConvert.Text = 'Convert';
            app.RTFButtonLoad = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RTFButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @RTFButtonLoadPushed, true);
            app.RTFButtonLoad.Text = 'Load';
            app.LoadedLinesTab = uitab(app.TabGroup);
            app.LoadedLinesTab.Title = 'Loaded lines';
            app.GridLayout = uigridlayout(app.LoadedLinesTab);
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.ProcessTab_Grid = uigridlayout(app.ProcessTab);
            app.SurveyProcessingPanel = uipanel(app.ProcessTab_Grid);
            app.SurveyProcessingPanel.Title = 'Survey processing';
            app.SurveyProcessingGrid = uigridlayout(app.SurveyProcessingPanel);
            app.SurveyBoundaries_Label = uilabel(app.SurveyProcessingGrid);
            app.SurveyBoundaries_Label.Text = 'Survey limits:';
            app.File_Button = uibutton(app.SurveyProcessingGrid, 'push');
            app.File_Button.ButtonPushedFcn = createCallbackFcn(app, @PTFile_ButtonPushed, true);
            app.File_Button.Text = 'File';
            app.Clear_Button = uibutton(app.SurveyProcessingGrid, 'push');
            app.Clear_Button.ButtonPushedFcn = createCallbackFcn(app, @PTClear_ButtonPushed, true);
            app.Clear_Button.Text = 'Clear';
            app.AnalyzesurveyButton = uibutton(app.SurveyProcessingGrid, 'push');
            app.AnalyzesurveyButton.ButtonPushedFcn = createCallbackFcn(app, @PTAnalyzesurveyButtonPushed, true);
            app.AnalyzesurveyButton.Text = 'Analyze survey';
            app.ShowSurveyAnalysis_CheckBox = uicheckbox(app.SurveyProcessingGrid);
            app.ShowSurveyAnalysis_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSurveyAnalysis_CheckBoxValueChanged, true);
            app.ShowSurveyAnalysis_CheckBox.Text = 'Show results';
            app.ExportSurveyAnalysis_Button = uibutton(app.SurveyProcessingGrid, 'push');
            app.ExportSurveyAnalysis_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportSurveyAnalysis_ButtonPushed, true);
            app.ExportSurveyAnalysis_Button.Text = 'Export survey analysis';
            app.Edit_Button = uibutton(app.SurveyProcessingGrid, 'push');
            app.Edit_Button.ButtonPushedFcn = createCallbackFcn(app, @Edit_ButtonPushed, true);
            app.Edit_Button.Text = {'Edit'; ''};
            app.Draw_Button = uibutton(app.SurveyProcessingGrid, 'push');
            app.Draw_Button.ButtonPushedFcn = createCallbackFcn(app, @Draw_ButtonPushed, true);
            app.Draw_Button.Text = 'Draw';
            app.DisplayPanel = uipanel(app.ProcessTab_Grid);
            app.DisplayPanel.Title = 'Display';
            app.DisplayGrid = uigridlayout(app.DisplayPanel);
            app.ButtonMotionTextArea = uitextarea(app.DisplayGrid);
            app.ButtonDownTextArea = uitextarea(app.DisplayGrid);
            app.AnalysisResultsPanel = uipanel(app.ProcessTab_Grid);
            app.AnalysisResultsPanel.Title = 'Analysis Results';
            app.AnalysisResults_Grid = uigridlayout(app.AnalysisResultsPanel);
            app.Label_3 = uilabel(app.AnalysisResults_Grid);
            app.Label_3.Text = '';
            app.AnalysisResults_TextArea = uitextarea(app.AnalysisResults_Grid);
            app.LineProcessing_Panel = uipanel(app.ProcessTab_Grid);
            app.LineProcessing_Panel.Title = 'Line processing';
            app.LineProcessing_Grid = uigridlayout(app.LineProcessing_Panel);
            app.GridSize_EditField = uieditfield(app.LineProcessing_Grid, 'numeric');
            app.GridSize_EditField.Limits = [0 Inf];
            app.GridSize_EditField.Value = 1;
            app.GridsizemLabel = uilabel(app.LineProcessing_Grid);
            app.GridsizemLabel.Text = 'Grid size (m):';
            app.Grid_Button = uibutton(app.LineProcessing_Grid, 'push');
            app.Grid_Button.ButtonPushedFcn = createCallbackFcn(app, @PTGrid_ButtonPushed, true);
            app.Grid_Button.Text = 'Grid';
            app.Analyze_Button = uibutton(app.LineProcessing_Grid, 'push');
            app.Analyze_Button.ButtonPushedFcn = createCallbackFcn(app, @PTAnalyze_ButtonPushed, true);
            app.Analyze_Button.Text = 'Analyze';
            app.GriddedDataDisplayed_DropDownMenu = uidropdown(app.LineProcessing_Grid);
            app.GriddedDataDisplayed_DropDownMenu.Items = {'-', 'Backscatter', 'Bathymetry', 'Slope', 'Mode'};
            app.GriddedDataDisplayed_DropDownMenu.ValueChangedFcn = createCallbackFcn(app, @PTGriddedDataDisplayed_DropDownMenuValueChanged, true);
            app.GriddedDataDisplayed_DropDownMenu.Value = '-';
            app.GriddeddatadisplayedDropDownLabel = uilabel(app.LineProcessing_Grid);
            app.GriddeddatadisplayedDropDownLabel.Text = 'Gridded data displayed:';
            app.ShowLineAnalysis_CheckBox = uicheckbox(app.LineProcessing_Grid);
            app.ShowLineAnalysis_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLineAnalysis_CheckBox_ValueChanged, true);
            app.ShowLineAnalysis_CheckBox.Text = 'Show line analysis';
            app.MainAxes = uiaxes(app.MainFigureGrid);
            zlabel(app.MainAxes, 'Z')
            app.MainAxes.XTickLabel = '';
            app.MainAxes.YTickLabel = '';
            app.MainAxes.Tag = 'MainAxes';
            app.SurveyResultAxes = uiaxes(app.MainFigureGrid);
            app.SurveyResultAxes.XTickLabel = '';
            app.SurveyResultAxes.YTickLabel = '';
            app.SurveyResultAxes.Visible = 'off';
            app.SurveyResultAxes.Tag = 'SurveyResultAxes';
            app.RFTContextMenu = uicontextmenu(app.MainFigure);
            % Assign app.RFTContextMenu
            app.RFTTable.ContextMenu = app.RFTContextMenu;
            app.SelectallfilesMenu = uimenu(app.RFTContextMenu);
            app.SelectallfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @RTFSelectAll, true);
            app.SelectallfilesMenu.Text = 'Select all files';
            app.SelectconvertedfilesMenu = uimenu(app.RFTContextMenu);
            app.SelectconvertedfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @RTFSelectConverted, true);
            app.SelectconvertedfilesMenu.Text = 'Select converted files';
            app.DeselectallfilesMenu = uimenu(app.RFTContextMenu);
            app.DeselectallfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @RTFDeselectAll, true);
            app.DeselectallfilesMenu.Text = 'Deselect all files';
            app.InverseselectionMenu = uimenu(app.RFTContextMenu);
            app.InverseselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @RTFInverseSelection, true);
            app.InverseselectionMenu.Text = 'Inverse selection';
            app.LLTContextMenu = uicontextmenu(app.MainFigure);
            % Assign app.LLTContextMenu
            app.ListBox.ContextMenu = app.LLTContextMenu;
            app.UnloadselectedlinesMenu = uimenu(app.LLTContextMenu);
            app.UnloadselectedlinesMenu.MenuSelectedFcn = createCallbackFcn(app, @LLTUnload, true);
            app.UnloadselectedlinesMenu.Text = 'Unload selected lines';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Iskaffe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end