classdef Ristretto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                     matlab.ui.Figure
        MainFigureGrid                 matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        RawFilesTab                    matlab.ui.container.Tab
        RFTGridLayout                  matlab.ui.container.GridLayout
        RFTTable                       matlab.ui.control.Table
        RFTFolderGridLayout            matlab.ui.container.GridLayout
        EditFieldLabel                 matlab.ui.control.Label
        RFTFolderEditField             matlab.ui.control.EditField
        RFTFolderButton                matlab.ui.control.Button
        RFTButtonsGridLayout           matlab.ui.container.GridLayout
        RFTButtonConvert               matlab.ui.control.Button
        RTFButtonLoad                  matlab.ui.control.Button
        LoadedLinesTab                 matlab.ui.container.Tab
        ProcessTab                     matlab.ui.container.Tab
        ProcessGrid                    matlab.ui.container.GridLayout
        ProcessingPanel                matlab.ui.container.Panel
        ProcessingGrid                 matlab.ui.container.GridLayout
        GridButton                     matlab.ui.control.Button
        GridresolutionmEditFieldLabel  matlab.ui.control.Label
        GridresolutionmEditField       matlab.ui.control.NumericEditField
        Map                            matlab.ui.control.UIAxes
        RFTContextMenu                 matlab.ui.container.ContextMenu
        SelectallMenu                  matlab.ui.container.Menu
        DeselectallMenu                matlab.ui.container.Menu
        InverseselectionMenu           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % general properties
        CoffeeCodeRootFolder % Description
        FDataVersion
        FData = [];
        
        % raw data properties
        rawDataFilesList = [];
        idxConverted
        idxLoaded
        
        % configuration
        datagramSource = 'X8';
        ellips = '';
        tmproj = '';
        
    end
    
    
    methods (Access = private)
        
        % Update Raw Files Tab
        function updateRawFilesTable(app,keepSelection)
            
            % save list of files selected
            if ~isempty(app.RFTTable.Data)
                idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            end
            
            % list raw_files in search path
            rawDataFolder = app.RFTFolderEditField.Value;
            app.rawDataFilesList = CFF_list_raw_files_in_dir(rawDataFolder);
            
            % check which are already converted
            [app.idxConverted,idxFDataUpToDate] = CFF_are_raw_files_converted(app.rawDataFilesList);
            
            % files with outdated version
            if any(idxFDataUpToDate==0)
                % set converted files with wrong version as "not converted"
                app.idxConverted(idxFDataUpToDate==0) = 0;
                % throw warning
                warning('One or several files in this folder have been previously converted using an outdated version of Espresso. They will require reconversion and thus show as NOT converted.');
            end
            
            % check which are currently loaded
            app.idxLoaded = CFF_are_raw_files_loaded(app.rawDataFilesList, app.FData);
            
            % prepare array
            n_rawfiles = numel(app.rawDataFilesList);
            [disp_folder, filename, ext] = CFF_fileparts_as_cell(CFF_onerawfileonly(app.rawDataFilesList));
            disp_files = strcat(filename, ext);
            for ii = 1:n_rawfiles
                if iscell(app.rawDataFilesList{ii})
                    % paired file
                    disp_files{ii} = strcat(disp_files{ii}, ' (paired)');
                end
            end
            new_entry = [num2cell(false(n_rawfiles, 1)), disp_files, disp_folder];
            
            % update table data
            app.RFTTable.Data = new_entry;
            
            % set files colors depending on status
            removeStyle(app.RFTTable);
            if ~isempty(new_entry)
                app.RFTTable.Visible = 'off';
                idx2subs = @(idx) cat(2,find([idx;0]),2.*ones(numel(find(idx)),1)); % function to turn files idx to table subscript indices
                styleNotConverted = uistyle('FontColor',[0.5 0.5 0.5]); % gray
                addStyle(app.RFTTable,styleNotConverted,'cell',idx2subs(~app.idxConverted));
                styleConvertedNotLoaded = uistyle('FontWeight','bold','FontColor','k'); % black
                addStyle(app.RFTTable,styleConvertedNotLoaded,'cell',idx2subs(app.idxConverted&~app.idxLoaded));
                styleLoaded = uistyle('FontWeight','bold','FontColor',[0.4660 0.6740 0.1880]); % green
                addStyle(app.RFTTable,styleLoaded,'cell',idx2subs(app.idxConverted&app.idxLoaded));
                app.RFTTable.Visible = 'on';
            end
            
            % re-select files selected
            if exist('keepSelection','var') && keepSelection==1 && ~isempty(idxFilesSelected)
                app.RFTTable.Visible = 'off';
                app.RFTTable.Data(idxFilesSelected,1) = {true};
                app.RFTTable.Visible = 'on';
            end
            
        end
        
        % Update Raw Files Tab
        function updateMap(app)
            
            % index of lines to show
            nLines = numel(app.FData);
            
            if nLines == 0
                return
            end
            
            nav_col = [0 0 0]; % black
            
            % initialize xlim and ylim for zoom extent
            xlim = [nan nan];
            ylim = [nan nan];
            cax  = [nan nan];
            
            % work line per line
            for ii = 1:nLines
                
                % get data
                fData = app.FData{ii};
                tag_id_nav = num2str(fData.ID,'%.0f_nav');
              
                % draw line navigation, with filename, ID, and callback when
                % clicking on it
                handle_plot_1 = plot(app.Map,fData.X_1P_pingE,fData.X_1P_pingN,...
                    'Tag',tag_id_nav,...
                    'Visible','on',...
                    'Color',nav_col);
                %'ButtonDownFcn',{@disp_wc_ping_cback,main_figure},...
                %'UserData',user_data);
                
                % draw points every dt seconds as subsampled navigation
                dt = 120; % in seconds
                idx_f = mod(floor(fData.X_1P_pingTSMIM/1e2)/10,dt)==0;
                idx_f(1) = 1;
                idx_f(end) = 1;
                
                plot(app.Map,fData.X_1P_pingE(idx_f),fData.X_1P_pingN(idx_f),'.',...
                    'Tag',tag_id_nav,...
                    'Visible','on',...
                    'Color',nav_col);
                %'ButtonDownFcn',{@disp_wc_ping_cback,main_figure},...
                %'UserData',user_data);
                
                % draw circle as start of line
                plot(app.Map,fData.X_1P_pingE(1),fData.X_1P_pingN(1),'o',...
                    'Tag',tag_id_nav,...
                    'Visible','on',...
                    'Color',nav_col);
                
                % draw end of line
                plot(app.Map,fData.X_1P_pingE(end),fData.X_1P_pingN(end),'s',...
                    'Tag',tag_id_nav,...
                    'Visible','on',...
                    'Color',nav_col);
                
                % Update zoom extents
                xlim(1) = nanmin(xlim(1),nanmin(fData.X_1P_pingE(:)));
                xlim(2) = nanmax(xlim(2),nanmax(fData.X_1P_pingE(:)));
                ylim(1) = nanmin(ylim(1),nanmin(fData.X_1P_pingN(:)));
                ylim(2) = nanmax(ylim(2),nanmax(fData.X_1P_pingN(:)));
                
                % now display grid
                if isfield(fData,'X_NE_bs')
                    
                    tag_id_Z = num2str(fData.ID,'%.0f_Z');
                    
                    E = fData.X_1E_2DgridEasting;
                    N = fData.X_N1_2DgridNorthing;
                    data = fData.X_NE_bs;
                    
                    wc_vis = 'on';
                    obj_wc = imagesc(app.Map,E,N,data,'Visible',wc_vis,'Tag',tag_id_Z,'AlphaData',~isnan(data));
                    
                    
                    % push grid to the bottom of the display stack so navigation is ontop
                    ch = get(app.Map,'children'); % get all plots from app.Map
                    [~,neworder] = sort(ismember(ch,obj_wc)); % reorder the handles to move the background lines to the background
                    set(app.Map,'children',ch(neworder)); % set the new order of all lines
                    
                    
                    cax = [nanmin(prctile(data(:),2),cax(1)) nanmax(prctile(data(:),95),cax(2))];
                    
                    % Update zoom extents
                    xlim(1) = nanmin(xlim(1),nanmin(fData.X_1E_2DgridEasting(:)));
                    xlim(2) = nanmax(xlim(2),nanmax(fData.X_1E_2DgridEasting(:)));
                    ylim(1) = nanmin(ylim(1),nanmin(fData.X_N1_2DgridNorthing(:)));
                    ylim(2) = nanmax(ylim(2),nanmax(fData.X_N1_2DgridNorthing(:)));
                    
                end
                
            end
            
            % ZOOM VIEW ADJUST
            auto_zoom_extent_flag = 1;
            if auto_zoom_extent_flag && all(~isnan(xlim)) && all(~isnan(ylim))
                
                % get current window size ratio
                pos = getpixelposition(app.Map);
                ratio_window = pos(4)/pos(3);
                
                ratio_data = diff(ylim)/diff(xlim);
                
                if ratio_data > ratio_window
                    % ylim_new = [ylim(1) ylim(2)];
                    ylim_new = [-diff(ylim),diff(ylim)]*1.2/2 + ylim(1) + diff(ylim)/2;
                    dx = diff(ylim)/ratio_window;
                    xlim_new = [-dx/2,dx/2] + xlim(1) + diff(xlim)/2;
                else
                    % xlim_new = xlim;
                    xlim_new = [-diff(xlim),diff(xlim)]*1.2/2 + xlim(1) + diff(xlim)/2;
                    dy = diff(xlim)*ratio_window;
                    ylim_new = [-dy/2,dy/2] + ylim(1) + diff(ylim)/2;
                end
                
                % set those new values to window
                set(app.Map,'YLim',ylim_new,'XLim',xlim_new);
                
            end
            
            % color scale adjust
            if all(~isnan(cax))
                caxis(app.Map,cax);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Add Ristretto folder to path
            RistrettoRootFolder = fileparts(which('Ristretto'));
            addpath(genpath(RistrettoRootFolder));
            
            % Add Coffee code to path
            app.CoffeeCodeRootFolder = 'C:\Users\Schimel_Alexandre\Code\MATLAB\Espresso\toolboxes\coffee';
            if isfolder(app.CoffeeCodeRootFolder)
                % Add to path
                addpath(genpath(app.CoffeeCodeRootFolder));
                app.FDataVersion = CFF_get_current_fData_version();
            else
                % To-do XXX1: Throw an alert to find manually a suitable
                % coffee folder
            end
            
            % Resize and reposition figure according to monitor's dimension
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.MainFigure.Position = [left bottom width height];
            
            % Main figure icon
            app.MainFigure.Icon = 'Espresso.png';
            
            % Set default folder location
            app.RFTFolderEditField.Value = cd;
            
            % Add callback after every zoom to reset equal aspect ratio
            h = zoom();
            h.ActionPostCallback = @(o, e) daspect(e.Axes, [1 1 1]);
            
            % Initialize Raw Files table
            app.updateRawFilesTable();
            
            % Initialize Map
            app.updateMap();
            
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            
            % reset zoom normal behavior
            h = zoom();
            h.ActionPostCallback = [];
            
            
            delete(app)
            
        end

        % Value changed function: RFTFolderEditField
        function RFTFolderEditFieldValueChanged(app, event)
            % Check if value is an existing folder
            requestedFolder = app.RFTFolderEditField.Value;
            if ~isfolder(requestedFolder)
                % Error dialog box
                uialert(app.MainFigure,'Folder not found','Invalid Folder','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure)
                % Reset previous field value
                app.RFTFolderEditField.Value = event.PreviousValue;
            else
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Button pushed function: RFTFolderButton
        function RFTFolderButtonPushed(app, event)
            % Open folder selection dialog box
            currentFolder = app.RFTFolderEditField.Value;
            newFolder = uigetdir(currentFolder,'Select folder of raw data files');
            if newFolder ~= 0
                % Update value of the FolderEditField
                app.RFTFolderEditField.Value = newFolder;
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Button pushed function: RFTButtonConvert
        function RFTButtonConvertPushed(app, event)
            % Get idx of selected files
            idxFilesToConvert = cell2mat(app.RFTTable.Data(:,1));
            % Default value to be overwritten if relevant
            forceReconvert = 0;
            % Prompt if some files already converted
            if any(idxFilesToConvert.*app.idxConverted)
                N = sum(idxFilesToConvert.*app.idxConverted);
                if N == 1
                    questDlgTxt = sprintf('1 file for which conversion was requested is already converted.\n\nDo you want to re-convert it?');
                else
                    questDlgTxt = sprintf('%i files for which conversion was requested are already converted.\n\nDo you want to re-convert them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Raw Files Conversion', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        forceReconvert = 1;
                    case 'No'
                        forceReconvert = 0;
                    case 'Cancel'
                        % abort converting
                        return
                end
            end
            % Get filenames
            rawFilesList = app.rawDataFilesList(idxFilesToConvert);
            % Convert files
            CFF_convert_raw_files(rawFilesList,...
                'conversionType','seafloor',...
                'saveFDataToDrive',1,...
                'forceReconvert',forceReconvert,...
                'outputFData',0,...
                'abortOnError',0,...
                'convertEvenIfDtgrmsMissing',0,...
                'dr_sub',1,...
                'db_sub',1,...
                'comms','multilines');
            
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
        end

        % Button pushed function: RTFButtonLoad
        function RTFButtonLoadPushed(app, event)
            % Get idx of selected files
            idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            idxFilesToLoad = idxFilesSelected & ~app.idxLoaded;
            % Idx of files requested but not converted yet
            idxNeedConversionFirst = idxFilesToLoad & ~app.idxConverted;
            % Prompt if some files not already converted
            if any(idxNeedConversionFirst)
                N = sum(idxNeedConversionFirst);
                if N == 1
                    questDlgTxt = sprintf('1 file for which loading was requested has not been converted yet.\n\nDo you want to convert it and load it?');
                else
                    questDlgTxt = sprintf('%i files for which loading was requested have not been converted yet.\n\nDo you want to convert them and load them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Converted Files Loading', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        % Convert those files
                        CFF_convert_raw_files(app.rawDataFilesList(idxNeedConversionFirst),...
                            'conversionType','seafloor',...
                            'saveFDataToDrive',1,...
                            'forceReconvert',0,...
                            'outputFData',0,...
                            'abortOnError',0,...
                            'convertEvenIfDtgrmsMissing',0,...
                            'dr_sub',1,...
                            'db_sub',1,...
                            'comms','multilines');
                        % Update Raw Files Table contents
                        app.updateRawFilesTable(1);
                    case 'No'
                        % Remove non-converted files from loading list
                        idxFilesToLoad = logical(idxFilesToLoad.*app.idxConverted);
                    case 'Cancel'
                        % abort loading
                        return
                end
            end
            % Get filenames
            toLoadFilesList = app.rawDataFilesList(idxFilesToLoad);
            if isempty(toLoadFilesList)
                return
            end
            % Geoprocess files
            fData_new = CFF_geoprocess_files(toLoadFilesList,...
                'datagramSource',app.datagramSource,...
                'ellips',app.ellips,...
                'tmproj',app.tmproj,...
                'saveFDataToDrive',0,...
                'outputFData',1,...
                'abortOnError',0,...
                'comms','multilines');
            % by default, CFF_geoprocess_files outputs a struct for a
            % single file. Turn to cell array if a single file was loaded
            if isstruct(fData_new)
                fData_new = {fData_new};
            end
            % update app's geoprocessing parameters
            if isempty(app.tmproj)
                app.ellips = fData_new{1}.MET_ellips;
                app.tmproj = fData_new{1}.MET_tmproj;
            end
            if isempty(app.datagramSource)
                app.datagramSource = fData_new{1}.MET_datagramSource;
            end
            % fix fData paths if necessary
            fData_new = CFF_fix_fData_paths(fData_new, toLoadFilesList);
            % Time-tag the individual fData structs
            for iF = 1:numel(fData_new)
                fData_new{iF}.ID = str2double(datestr(now,'yyyymmddHHMMSSFFF'));
                pause(1e-2); % pause here to ensure unique IDs
            end
            % add new fData to old one
            app.FData = [app.FData,fData_new];
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
            % Update Map
            updateMap(app);
        end

        % Menu selected function: SelectallMenu
        function SelectallMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {true}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: DeselectallMenu
        function DeselectallMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {false}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: InverseselectionMenu
        function InverseselectionMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {~x}, app.RFTTable.Data(:,1));
        end

        % Button pushed function: GridButton
        function GridButtonPushed(app, event)
            
            % init
            res = app.GridresolutionmEditField.Value;
            comms = CFF_Comms('multilines');
            comms.start('Gridding bathymetry and seafloor backscatter');
            idx_fData = 1:numel(app.FData);
            nFiles = numel(idx_fData);
            comms.progress(0,nFiles);
            u = 0;

            % Process per file
            for ii = idx_fData(:)'
                % iterator update
                u = u+1;
                % file name
                rawFile = app.FData{ii}.ALLfilename;
                if numel(rawFile)==1
                    rawFile = rawFile{1};
                end
                % display for this file
                if ischar(rawFile)
                    filename = CFF_file_name(rawFile,1);
                    comms.step(sprintf('%i/%i: file %s',ii,nFiles,filename));
                else
                    % paired files
                    filename_1 = CFF_file_name(rawFile{1},1);
                    filename_2_ext = CFF_file_extension(rawFile{2});
                    comms.step(sprintf('%i/%i: pair of files %s and %s',ii,nFiles,filename_1,filename_2_ext));
                end
                % grid bathy
                comms.info('Gridding bathymetry');
                [gridZ, gridE, gridN] = CFF_grid_2D_data(app.FData{ii}.X_BP_bottomEasting,...
                    app.FData{ii}.X_BP_bottomNorthing,...
                    app.FData{ii}.X_BP_bottomHeight,...
                    res);
                % grid BS
                % DEV NOTE: The bathy above can be gridded because it comes with
                % matching easting and northing. However, there is no "X_BP_bottom"
                % backscatter field. The only seafloor backscatter available comes from
                % X8 datagrams. If this was the datagram source used, then the
                % dimensions of X8_BP_ReflectivityBS match X_BP_bottomNorthing/Easting
                % and so they can be used to grid that backscatter. But if the datagram
                % source used was WC or AP, then that X8 backscatter might not have the
                % same dimensions. If this is the case, then we don't have a matching
                % easting and northing for backscatter and it cannot be gridded.
                if isfield(app.FData{ii},'X8_BP_ReflectivityBS') && all(size(app.FData{ii}.X8_BP_ReflectivityBS)==size(app.FData{ii}.X_BP_bottomEasting))
                    comms.info('Gridding seafloor backscatter');
                    gridBS = CFF_grid_2D_data(app.FData{ii}.X_BP_bottomEasting,...
                        app.FData{ii}.X_BP_bottomNorthing,...
                        app.FData{ii}.X8_BP_ReflectivityBS,...
                        res);
                else
                    gridBS = zeros(size(gridE),'single');
                    gridBS(isnan(gridZ)) = NaN;
                end
                % save
                app.FData{ii}.X_NE_bathy = gridZ;
                app.FData{ii}.X_NE_bs    = gridBS;
                app.FData{ii}.X_1E_2DgridEasting  = gridE(1,:);
                app.FData{ii}.X_N1_2DgridNorthing = gridN(:,1);
                app.FData{ii}.X_1_2DgridHorizontalResolution = res;
                % successful end of this iteration
                comms.info('Done');
                % communicate progress
                comms.progress(ii,nFiles);
            end
            
            % end message
            comms.finish('Done');
            
            % Update Map
            updateMap(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Ristretto';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.MainFigureGrid = uigridlayout(app.MainFigure);
            app.TabGroup = uitabgroup(app.MainFigureGrid);
            app.RawFilesTab = uitab(app.TabGroup);
            app.RawFilesTab.Title = 'Raw files';
            app.RFTGridLayout = uigridlayout(app.RawFilesTab);
            app.RFTTable = uitable(app.RFTGridLayout);
            app.RFTTable.ColumnName = {''; 'File'; 'Folder'};
            app.RFTTable.RowName = {};
            app.RFTTable.ColumnSortable = [false false false];
            app.RFTTable.ColumnEditable = [true false false];
            app.RFTFolderGridLayout = uigridlayout(app.RFTGridLayout);
            app.EditFieldLabel = uilabel(app.RFTFolderGridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.RFTFolderEditField = uieditfield(app.RFTFolderGridLayout, 'text');
            app.RFTFolderEditField.ValueChangedFcn = createCallbackFcn(app, @RFTFolderEditFieldValueChanged, true);
            app.RFTFolderButton = uibutton(app.RFTFolderGridLayout, 'push');
            app.RFTFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RFTFolderButtonPushed, true);
            app.RFTFolderButton.Icon = 'foldericon.gif';
            app.RFTFolderButton.Text = '';
            app.RFTButtonsGridLayout = uigridlayout(app.RFTGridLayout);
            app.RFTButtonConvert = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RFTButtonConvert.ButtonPushedFcn = createCallbackFcn(app, @RFTButtonConvertPushed, true);
            app.RFTButtonConvert.Text = 'Convert';
            app.RTFButtonLoad = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RTFButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @RTFButtonLoadPushed, true);
            app.RTFButtonLoad.Text = 'Load';
            app.LoadedLinesTab = uitab(app.TabGroup);
            app.LoadedLinesTab.Title = 'Loaded lines';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.ProcessGrid = uigridlayout(app.ProcessTab);
            app.ProcessingPanel = uipanel(app.ProcessGrid);
            app.ProcessingPanel.Title = 'Processing';
            app.ProcessingGrid = uigridlayout(app.ProcessingPanel);
            app.GridButton = uibutton(app.ProcessingGrid, 'push');
            app.GridButton.ButtonPushedFcn = createCallbackFcn(app, @GridButtonPushed, true);
            app.GridButton.Text = 'Grid';
            app.GridresolutionmEditFieldLabel = uilabel(app.ProcessingGrid);
            app.GridresolutionmEditFieldLabel.Text = 'Grid resolution (m)';
            app.GridresolutionmEditField = uieditfield(app.ProcessingGrid, 'numeric');
            app.GridresolutionmEditField.Limits = [0 Inf];
            app.GridresolutionmEditField.Value = 1;
            app.Map = uiaxes(app.MainFigureGrid);
            zlabel(app.Map, 'Z')
            app.Map.XTickLabel = '';
            app.Map.YTickLabel = '';
            app.RFTContextMenu = uicontextmenu(app.MainFigure);
            % Assign app.RFTContextMenu
            app.RFTTable.ContextMenu = app.RFTContextMenu;
            app.SelectallMenu = uimenu(app.RFTContextMenu);
            app.SelectallMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectallMenuSelected, true);
            app.SelectallMenu.Text = 'Select all';
            app.DeselectallMenu = uimenu(app.RFTContextMenu);
            app.DeselectallMenu.MenuSelectedFcn = createCallbackFcn(app, @DeselectallMenuSelected, true);
            app.DeselectallMenu.Text = 'Deselect all';
            app.InverseselectionMenu = uimenu(app.RFTContextMenu);
            app.InverseselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @InverseselectionMenuSelected, true);
            app.InverseselectionMenu.Text = 'Inverse selection';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ristretto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end